classdef appbase2mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Appbase2MlappUIFigure       matlab.ui.Figure
        WelcomeLabel                matlab.ui.control.Label
        SelectedFileEditFieldLabel  matlab.ui.control.Label
        SelectedFileEditField       matlab.ui.control.EditField
        BrowseFileButton            matlab.ui.control.Button
        ConvertButton               matlab.ui.control.Button
        GetPropsButton              matlab.ui.control.Button
        PublicPropsLabel            matlab.ui.control.Label
        PublicPropsListBox          matlab.ui.control.ListBox
        Tree                        matlab.ui.container.Tree
        GroupButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        filePath % The file to be converted
        props
        selectedProperties % Description
        fullPath
        uifig matlab.ui.Figure % Description
        output1
    end
    
    methods (Access = private)
        
        function results = getid(app)
            results = cell(4,1);
            letters ='A':'J';
            numbers = 1:9;
            
            % 8 char long, x2 chars per loop
            for c = 1:4
                str=letters(randi(numel(letters)));
                nm =numbers(randi(numel(numbers)));
                results{c} = strcat(char(str),num2str(nm));
            end
            results = char(horzcat(results{:}));
        end
        
        function groups = groupTheGroups(app)
            n = size(app.Tree.Children,1);
            groups = cell(1,n);
            
            for c = 1:size(app.Tree.Children,1)
                id = getid(app);
                for k = 1:size(app.Tree.Children(c).Children,1)
                    propName = app.Tree.Children(c).Children(k).Text;
                    for lo = 1:size(app.uifig.Children,1)
                        if strcmp(app.uifig.Children(lo).Tag, propName)
                            app.uifig.Children(lo).DesignTimeProperties(1).GroupId = id;
                            break;
                        end
                    end
                end
                groupProps = struct('Id', '', 'ParentGroupId', '');
                groupProps.Id = id;
                groups{c} = groupProps;
            end
        end
        
        function designTimeProperty = getDesignTimeProperty(~, propName)
            %             designTimeProperty(1).CodeName = propName;
            %             designTimeProperty(2).GroupId = "";
            %             designTimeProperty(3).ComponentCode = {{}};
            designTimeProperty = struct(...
                'CodeName', propName,...
                'GroupId', '',...
                'ComponentCode', {{}});
        end
        
        function compTag(app, compObj)
            try                
                addDesignTimeProp(compObj);
                compObj.DesignTimeProperties = getDesignTimeProperty(app, compObj.Tag);
                ch = get(compObj, 'children');
            catch x
                disp(x)
                ch = [];
            end
            if ~isempty(ch)
                for k = 1:length(ch)
                    app.compTag(ch(k)); % Recursive
                end
            end
            function addDesignTimeProp(obj)
                if not(isprop(obj, "DesignTimeProperties"))
                    obj.addprop("DesignTimeProperties");
                end
            end
        end
        
        function cp(~, inFigNum,OutFigNum)
            % this program copies a figure to another figure
            % example: CloneFig(1,4) would copy Fig. 1 to Fig. 4
            % Matt Fetterman, 2009
            % pretty much taken from Matlab Technical solutions:
            % http://www.mathworks.com/support/solutions/en/data/1-1UTBOL/?solution=1-1UTBOL
            %                         hf1=figure(inFigNum);
            %                         hf2=figure(OutFigNum);
            %                         clf;
            compCopy(inFigNum,OutFigNum);
            % Output fig        
            
            function compCopy(op, np)
                %COMPCOPY copies a figure object represented by "op" and its % descendants to another figure "np" preserving the same hierarchy.
                try
                    ch = get(op, 'children');
                catch x
                    disp(x)
                    ch = [];
                end
                if ~isempty(ch)
                    try
                        disp (ch)
                    catch ME
                        disp(ME)
                    end
                    nh = copyobj(ch,np);
                    for k = 1:length(ch)
                        compCopy(ch(k),nh(k));
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filePath = "";
            app.selectedProperties = [];
            app.props = [];
            app.fullPath = "";
            app.uifig = appdesigner.internal.componentadapter.uicomponents.adapter.createUIFigure();
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            initialUserPath = strcat(getenv("USERPROFILE"),'\Documents\MATLAB');
            
            [filename, filepath, ~] = uigetfile('../*.m', 'Select M file', initialUserPath);
            if not(isempty(filename))
                app.filePath = [filepath, filename];
                figure(app.Appbase2MlappUIFigure);
                
                app.SelectedFileEditField.Value = app.filePath;
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            if not(isempty(app.uifig))
                groups = groupTheGroups(app);
                fileW = appdesigner.internal.serialization.MLAPPSerializer(app.fullPath,app.uifig);
                fileW.Groups = groups;
                fileW.save()
                
                delete(app.output1)
            end
            
            
        end

        % Value changed function: PublicPropsListBox
        function PublicPropsListBoxValueChanged(app, event)
            value = app.PublicPropsListBox.Value;
            if size(value, 2) >=2
                app.selectedProperties = value;
            end
        end

        % Button pushed function: GroupButton
        function GroupButtonPushed(app, event)
            n =size(app.Tree.Children,1)+1;
            group = uitreenode(app.Tree,'Text',strcat("Group ", num2str(n)),'NodeData',[]);
            size(app.selectedProperties,2)
            for c = 1:size(app.selectedProperties,2)
                child = uitreenode(group,'Text',app.selectedProperties{c},'NodeData',[]);
            end
        end

        % Button pushed function: GetPropsButton
        function GetPropsButtonPushed(app, event)
            [filepath, filename] = fileparts(app.filePath);
            % The only that is missing from ".m" is "lapp"
            fileName = char(strcat(filename, '.mlapp'));
            app.fullPath = [filepath, '\', fileName];
            % May be there needs to be a relative path for the app to run?
            addpath(filepath);
            
            % Should be able to run in the first place
            [~, app.output1] = evalc(filename);
            drawnow();
            app.props = properties(app.output1);
            % Ignore the first one, because there is no need to group
            % uifigure with it is children
            app.PublicPropsListBox.Items = app.props(2:end);
            
            for c = 1:length(app.props)
                thisprop = app.props{c};
                disp(thisprop)
                thisprop_value = app.output1.(thisprop);
                
                thisprop_value.Tag = thisprop; % Tag it with it is variable name
                
                if isa(thisprop_value, 'matlab.ui.Figure')
                    app.uifig.Name = thisprop_value.Name;
                    app.uifig.Position = thisprop_value.Position;
                    app.uifig.Tag = thisprop;
                    currentUifigure = thisprop_value;
                end
            end
            
            app.cp(currentUifigure, app.uifig);
            app.compTag(app.uifig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Appbase2MlappUIFigure = uifigure('Visible', 'off');
            app.Appbase2MlappUIFigure.Name = 'Appbase 2 Mlapp';
            app.WelcomeLabel = uilabel(app.Appbase2MlappUIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.SelectedFileEditFieldLabel = uilabel(app.Appbase2MlappUIFigure);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.Appbase2MlappUIFigure, 'text');
            app.SelectedFileEditField.Editable = 'off';
            app.BrowseFileButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File...';
            app.ConvertButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.GetPropsButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.GetPropsButton.ButtonPushedFcn = createCallbackFcn(app, @GetPropsButtonPushed, true);
            app.GetPropsButton.Text = 'Get Props';
            app.PublicPropsLabel = uilabel(app.Appbase2MlappUIFigure);
            app.PublicPropsLabel.Text = {'Public '; 'Props'};
            app.PublicPropsListBox = uilistbox(app.Appbase2MlappUIFigure);
            app.PublicPropsListBox.Items = {};
            app.PublicPropsListBox.Multiselect = 'on';
            app.PublicPropsListBox.ValueChangedFcn = createCallbackFcn(app, @PublicPropsListBoxValueChanged, true);
            app.PublicPropsListBox.Value = {};
            app.Tree = uitree(app.Appbase2MlappUIFigure);
            app.Tree.Multiselect = 'on';
            app.GroupButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.GroupButton.ButtonPushedFcn = createCallbackFcn(app, @GroupButtonPushed, true);
            app.GroupButton.Text = 'Group >>>';
            % Show the figure after all components are created
            app.Appbase2MlappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appbase2mlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Appbase2MlappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Appbase2MlappUIFigure)
        end
    end
end